classdef normalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TestResultLabel          matlab.ui.control.Label
        TESTButton               matlab.ui.control.Button
        stddeviationSlider       matlab.ui.control.Slider
        stddeviationSliderLabel  matlab.ui.control.Label
        meanSlider               matlab.ui.control.Slider
        meanSliderLabel          matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        x % x-axis
        p % calculated scores across each element of x
    end
    
    methods (Access = private)
        function updatePlot(app)
            app.x = -10:0.1:10;
            mu = app.meanSlider.Value;
            sigma = app.stddeviationSlider.Value;
            z = (app.x - mu) / sigma;
            app.p = 1 / (sqrt(2*pi)*sigma) * exp(-0.5 * z.^2);
            plot(app.UIAxes, app.x, app.p);
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XLim = [-10 10];
            title(app.UIAxes, 'Normal Distribution');
        end
                %%%%%%%%%%%%FOR TESTING: DO NOT CHANGE THE CODE%%%%%%%%%%%%%%%%%
        function cleaner(app)
            resultLabel = app.TestResultLabel;
            resultLabel.Visible = false;
            resultLabel.FontColor = [0.0,0.0,0.0];
            resultLabel.BackgroundColor = [0.0,0.0,0.0];
        end
        %%%%%%%%%%%%FOR TESTING: DO NOT CHANGE THE CODE%%%%%%%%%%%%%%%%%
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
                function startupFcn(app)
            app.x = -10:0.1:10;
            mu = app.meanSlider.Value;
            sigma = app.stddeviationSlider.Value;
            z = (app.x - mu) / sigma;
            app.p = 1 / (sqrt(2*pi)*sigma) * exp(-0.5 * z.^2);
            plot(app.UIAxes, app.x, app.p);
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XLim = [-10 10];
            title(app.UIAxes, 'Normal Distribution');
        end

        % Value changed function: meanSlider
        function meanSliderValueChanged(app, event)
            app.x = -10:0.1:10;
            mu = app.meanSlider.Value;
            sigma = app.stddeviationSlider.Value;
            z = (app.x - mu) / sigma;
            app.p = 1 / (sqrt(2*pi)*sigma) * exp(-0.5 * z.^2);
            plot(app.UIAxes, app.x, app.p);
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XLim = [-10 10];
            title(app.UIAxes, 'Normal Distribution');
        end

        % Value changed function: stddeviationSlider
        function stddeviationSliderValueChanged(app, event)
            app.x = -10:0.1:10;
            mu = app.meanSlider.Value;
            sigma = app.stddeviationSlider.Value;
            z = (app.x - mu) / sigma;
            app.p = 1 / (sqrt(2*pi)*sigma) * exp(-0.5 * z.^2);
            plot(app.UIAxes, app.x, app.p);
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XLim = [-10 10];
            title(app.UIAxes, 'Normal Distribution');
        end

        % Button pushed function: TESTButton
        function TESTButtonPushed(app, event)
            %%%%%%%%%%FOR TESTING: DO NOT CHANGE THE CODE BELOW%%%%%%%%%%%
            resultLabel = app.TestResultLabel;
            resultLabel.Visible = true;
            resultLabel.Text = "TEST IN-PROGRESS....";
            pause(0.5);
            result = runtests("testNormalApp")
            
            passed = 0;
            failed = 0;
            incomplete = 0;
            for i=1:numel(result)
                passed = passed + result(i).Passed;
                failed = failed + result(i).Failed;
                incomplete = incomplete + result(i).Incomplete;
            end
            resultLabel = app.TestResultLabel;
            resultLabel.Visible = true;
            if failed + incomplete > 0
                resultLabel.Text = "TEST FAILED. PLEASE CHECK COMMAND WINDOW FOR MORE DETAILS";
                resultLabel.FontColor = [1.0,1.0,1.0];
                resultLabel.BackgroundColor = [1.0,0.0,0.0];
            else
                resultLabel.Text = "TEST SUCCEEDED!";
                resultLabel.FontColor = [1.0,1.0,1.0];
                resultLabel.BackgroundColor = [0.0,1.0,0.0];
            end
            f = parfeval(backgroundPool,@pause,0,10);
            afterEach(f,@app.cleaner,0);
            %%%%%%%%FOR TESTING: DO NOT CHANGE THE CODE ABOVE%%%%%%%%%%%%%%
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Normal Distribution')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.meanSliderLabel = uilabel(app.UIFigure);
            app.meanSliderLabel.Text = 'mean';
            app.meanSlider = uislider(app.UIFigure);
            app.meanSlider.Limits = [-10 10];
            app.meanSlider.ValueChangedFcn = createCallbackFcn(app, @meanSliderValueChanged, true);
            app.stddeviationSliderLabel = uilabel(app.UIFigure);
            app.stddeviationSliderLabel.Text = 'std. deviation';
            app.stddeviationSlider = uislider(app.UIFigure);
            app.stddeviationSlider.Limits = [0 5];
            app.stddeviationSlider.ValueChangedFcn = createCallbackFcn(app, @stddeviationSliderValueChanged, true);
            app.stddeviationSlider.Value = 1;
            app.TESTButton = uibutton(app.UIFigure, 'push');
            app.TESTButton.ButtonPushedFcn = createCallbackFcn(app, @TESTButtonPushed, true);
            app.TESTButton.Text = 'TEST';
            app.TestResultLabel = uilabel(app.UIFigure);
            app.TestResultLabel.Visible = 'off';
            app.TestResultLabel.Text = 'TestResult';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = normalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end